classdef PingPong < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		PingPongTaskUIFigure          matlab.ui.Figure
		GridLayout                    matlab.ui.container.GridLayout
		LeftPanel                     matlab.ui.container.Panel
		GridLayout2                   matlab.ui.container.GridLayout
		EnvyModeCheckBox              matlab.ui.control.CheckBox
		BackDeviceDropDown            matlab.ui.control.DropDown
		BackDeviceDropDownLabel       matlab.ui.control.Label
		FrontDeviceDropDown           matlab.ui.control.DropDown
		FrontDeviceDropDownLabel      matlab.ui.control.Label
		ITIIncorrectEditField         matlab.ui.control.NumericEditField
		ITIIncorrectEditFieldLabel    matlab.ui.control.Label
		ITICorrectEditField           matlab.ui.control.NumericEditField
		ITICorrectEditFieldLabel      matlab.ui.control.Label
		RewardTypeBDropDown           matlab.ui.control.DropDown
		RewardTypeBDropDownLabel      matlab.ui.control.Label
		RewardTypeADropDown           matlab.ui.control.DropDown
		RewardTypeADropDownLabel      matlab.ui.control.Label
		PedestalWidthEditField        matlab.ui.control.NumericEditField
		PedestalWidthEditFieldLabel   matlab.ui.control.Label
		PedestalHeightEditField       matlab.ui.control.NumericEditField
		PedestalHeightEditFieldLabel  matlab.ui.control.Label
		nirSmartIPEditField           matlab.ui.control.EditField
		nirSmartIPEditFieldLabel      matlab.ui.control.Label
		DebugCheckBox                 matlab.ui.control.CheckBox
		TogglePedestalCheckBox        matlab.ui.control.CheckBox
		CoopTimerEditField            matlab.ui.control.NumericEditField
		CoopTimerEditFieldLabel       matlab.ui.control.Label
		TrialTimeEditField            matlab.ui.control.NumericEditField
		TrialTimeEditFieldLabel       matlab.ui.control.Label
		NumberofTrialsEditField       matlab.ui.control.NumericEditField
		NumberofTrialsEditFieldLabel  matlab.ui.control.Label
		SideDropDown                  matlab.ui.control.DropDown
		SideDropDownLabel             matlab.ui.control.Label
		VerboseCheckBox               matlab.ui.control.CheckBox
		PxPerCmEditField              matlab.ui.control.NumericEditField
		PxPerCmEditFieldLabel         matlab.ui.control.Label
		DistanceEditField             matlab.ui.control.NumericEditField
		DistanceEditFieldLabel        matlab.ui.control.Label
		DividerWidthEditField         matlab.ui.control.NumericEditField
		DividerWidthEditFieldLabel    matlab.ui.control.Label
		WallColourEditField           matlab.ui.control.EditField
		WallColourEditFieldLabel      matlab.ui.control.Label
		StimulusDropDown              matlab.ui.control.DropDown
		StimulusDropDownLabel         matlab.ui.control.Label
		ArduinoBEditField             matlab.ui.control.EditField
		ArduinoBEditFieldLabel        matlab.ui.control.Label
		ArduinoAEditField             matlab.ui.control.EditField
		ArduinoAEditFieldLabel        matlab.ui.control.Label
		StartBEditField               matlab.ui.control.NumericEditField
		StartBEditFieldLabel          matlab.ui.control.Label
		StartAEditField               matlab.ui.control.NumericEditField
		StartAEditFieldLabel          matlab.ui.control.Label
		SubjectBEditField             matlab.ui.control.EditField
		SubjectBEditFieldLabel        matlab.ui.control.Label
		SubjectAEditField             matlab.ui.control.EditField
		SubjectAEditFieldLabel        matlab.ui.control.Label
		CeilingEditField              matlab.ui.control.NumericEditField
		CeilingEditFieldLabel         matlab.ui.control.Label
		FloorEditField                matlab.ui.control.NumericEditField
		FloorEditFieldLabel           matlab.ui.control.Label
		RightWallEditField            matlab.ui.control.NumericEditField
		RightWallEditFieldLabel       matlab.ui.control.Label
		LeftWallEditField             matlab.ui.control.NumericEditField
		LeftWallEditFieldLabel        matlab.ui.control.Label
		ExperimentDropDown            matlab.ui.control.DropDown
		ExperimentDropDownLabel       matlab.ui.control.Label
		BallSizeEditField             matlab.ui.control.NumericEditField
		BallSizeEditFieldLabel        matlab.ui.control.Label
		SensitivityEditField          matlab.ui.control.NumericEditField
		SensitivityEditFieldLabel     matlab.ui.control.Label
		ElasticityEditField           matlab.ui.control.NumericEditField
		ElasticityEditFieldLabel      matlab.ui.control.Label
		LinearDampingEditField        matlab.ui.control.NumericEditField
		LinearDampingEditFieldLabel   matlab.ui.control.Label
		DummyTouchCheckBox            matlab.ui.control.CheckBox
		STARTButton                   matlab.ui.control.Button
		QUITButton                    matlab.ui.control.Button
		RightPanel                    matlab.ui.container.Panel
		GridLayout3                   matlab.ui.container.GridLayout
		UIAxis1                       matlab.ui.control.UIAxes
		UIAxis2                       matlab.ui.control.UIAxes
	end

	% Properties that correspond to apps with auto-reflow
	properties (Access = private)
		onePanelWidth = 576;
	end

	% Callbacks that handle component events
	methods (Access = private)

		% Button pushed function: STARTButton
		function STARTButtonPushed(app, event)
			in.ntrials = app.NumberofTrialsEditField.Value;
			in.trialtime = app.TrialTimeEditField.Value;
			in.iticorrect = app.ITICorrectEditField.Value;
			in.itiincorrect = app.ITIIncorrectEditField.Value;
			in.distance = app.DistanceEditField.Value;
			in.ppc = app.PxPerCmEditField.Value;
			in.startA = app.StartAEditField.Value;
			in.startB = app.StartBEditField.Value;
			in.ballSize = app.BallSizeEditField.Value;
			in.linearD = app.LinearDampingEditField.Value;
			in.elasticity = app.ElasticityEditField.Value;
			in.sensitivity = app.SensitivityEditField.Value;
			in.floor = app.FloorEditField.Value;
			in.pedwidth = app.PedestalWidthEditField.Value;
			in.pedheight = app.PedestalHeightEditField.Value;
			in.leftW = app.LeftWallEditField.Value;
			in.rightW = app.RightWallEditField.Value;
			in.dWidth = app.DividerWidthEditField.Value;
			in.ceiling = app.CeilingEditField.Value;
			in.subjecta = app.SubjectAEditField.Value;
			in.subjectb = app.SubjectBEditField.Value;
			in.arduinoa = app.ArduinoAEditField.Value;
			in.arduinob = app.ArduinoBEditField.Value;
			in.image = app.StimulusDropDown.Value;
			in.task = app.ExperimentDropDown.Value;
			in.cooptime = app.CoopTimerEditField.Value;
			in.side = app.SideDropDown.Value;
			in.axis1 = app.UIAxis1;
			in.axis2 = app.UIAxis2;
			in.dummy = app.DummyTouchCheckBox.Value;
			in.verbose = app.VerboseCheckBox.Value;
			in.debug = app.DebugCheckBox.Value;
			in.wallColour = eval(app.WallColourEditField.Value);
			in.togglepedestal =  app.TogglePedestalCheckBox.Value;
			in.nirsmartip = app.nirSmartIPEditField.Value;
            in.fronttype = app.RewardTypeADropDown.Value;
            in.backtype = app.RewardTypeBDropDown.Value;
			in.frontdevice = str2double(app.FrontDeviceDropDown.Value);
			in.backdevice = str2double(app.BackDeviceDropDown.Value);
			in.envy = app.EnvyModeCheckBox.Value;
			runPingPong(in);
		end

		% Button pushed function: QUITButton
		function QUITButtonPushed(app, event)
			close(app.PingPongTaskUIFigure);
		end

		% Value changed function: FrontDeviceDropDown
		function FrontDeviceDropDownValueChanged(app, event)
			value = app.FrontDeviceDropDown.Value;
			value = str2double(value);
			if value == 1
				app.BackDeviceDropDown.Value = '2';
			else
				app.BackDeviceDropDown.Value = '1';
			end
		end

		% Value changed function: BackDeviceDropDown
		function BackDeviceDropDownValueChanged(app, event)
			value = app.BackDeviceDropDown.Value;
			value = str2double(value);
			if value == 1
				app.FrontDeviceDropDown.Value = '2';
			else
				app.FrontDeviceDropDown.Value = '1';
			end
		end

		% Changes arrangement of the app based on UIFigure width
		function updateAppLayout(app, event)
			currentFigureWidth = app.PingPongTaskUIFigure.Position(3);
			if(currentFigureWidth <= app.onePanelWidth)
			    % Change to a 2x1 grid
			    app.GridLayout.RowHeight = {870, 870};
			    app.GridLayout.ColumnWidth = {'1x'};
			    app.RightPanel.Layout.Row = 2;
			    app.RightPanel.Layout.Column = 1;
			else
			    % Change to a 1x2 grid
			    app.GridLayout.RowHeight = {'1x'};
			    app.GridLayout.ColumnWidth = {529, '1x'};
			    app.RightPanel.Layout.Row = 1;
			    app.RightPanel.Layout.Column = 2;
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.PingPongTaskUIFigure = uifigure('Visible', 'off');
			app.PingPongTaskUIFigure.Name = 'Ping Pong Task';
			app.PingPongTaskUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
			app.GridLayout = uigridlayout(app.PingPongTaskUIFigure);
			app.LeftPanel = uipanel(app.GridLayout);
			app.GridLayout2 = uigridlayout(app.LeftPanel);
			app.QUITButton = uibutton(app.GridLayout2, 'push');
			app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @QUITButtonPushed, true);
			app.QUITButton.Text = 'QUIT';
			app.STARTButton = uibutton(app.GridLayout2, 'push');
			app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
			app.STARTButton.Text = 'START';
			app.DummyTouchCheckBox = uicheckbox(app.GridLayout2);
			app.DummyTouchCheckBox.Text = 'Dummy Touch';
			app.LinearDampingEditFieldLabel = uilabel(app.GridLayout2);
			app.LinearDampingEditFieldLabel.Text = 'Linear Damping';
			app.LinearDampingEditField = uieditfield(app.GridLayout2, 'numeric');
			app.LinearDampingEditField.Value = 0.1;
			app.ElasticityEditFieldLabel = uilabel(app.GridLayout2);
			app.ElasticityEditFieldLabel.Text = 'Elasticity';
			app.ElasticityEditField = uieditfield(app.GridLayout2, 'numeric');
			app.ElasticityEditField.Value = 0.8;
			app.SensitivityEditFieldLabel = uilabel(app.GridLayout2);
			app.SensitivityEditFieldLabel.Text = 'Sensitivity';
			app.SensitivityEditField = uieditfield(app.GridLayout2, 'numeric');
			app.SensitivityEditField.Value = 6;
			app.BallSizeEditFieldLabel = uilabel(app.GridLayout2);
			app.BallSizeEditFieldLabel.Text = 'Ball Size';
			app.BallSizeEditField = uieditfield(app.GridLayout2, 'numeric');
			app.BallSizeEditField.Value = 3;
			app.ExperimentDropDownLabel = uilabel(app.GridLayout2);
			app.ExperimentDropDownLabel.Text = 'Experiment';
			app.ExperimentDropDown = uidropdown(app.GridLayout2);
			app.ExperimentDropDown.Items = {'Control', 'Coaction', 'Cooperation', 'CooperationTime', 'Competition'};
			app.ExperimentDropDown.Value = 'Control';
			app.LeftWallEditFieldLabel = uilabel(app.GridLayout2);
			app.LeftWallEditFieldLabel.Text = 'Left Wall';
			app.LeftWallEditField = uieditfield(app.GridLayout2, 'numeric');
			app.LeftWallEditField.Value = 2;
			app.RightWallEditFieldLabel = uilabel(app.GridLayout2);
			app.RightWallEditFieldLabel.Text = 'Right Wall';
			app.RightWallEditField = uieditfield(app.GridLayout2, 'numeric');
			app.RightWallEditField.Value = 2;
			app.FloorEditFieldLabel = uilabel(app.GridLayout2);
			app.FloorEditFieldLabel.Text = 'Floor';
			app.FloorEditField = uieditfield(app.GridLayout2, 'numeric');
			app.FloorEditField.Value = 4;
			app.CeilingEditFieldLabel = uilabel(app.GridLayout2);
			app.CeilingEditFieldLabel.Text = 'Ceiling';
			app.CeilingEditField = uieditfield(app.GridLayout2, 'numeric');
			app.SubjectAEditFieldLabel = uilabel(app.GridLayout2);
			app.SubjectAEditFieldLabel.Text = 'Subject A';
			app.SubjectAEditField = uieditfield(app.GridLayout2, 'text');
			app.SubjectAEditField.Value = 'Ping';
			app.SubjectBEditFieldLabel = uilabel(app.GridLayout2);
			app.SubjectBEditFieldLabel.Text = 'Subject B';
			app.SubjectBEditField = uieditfield(app.GridLayout2, 'text');
			app.SubjectBEditField.Value = 'Pong';
			app.StartAEditFieldLabel = uilabel(app.GridLayout2);
			app.StartAEditFieldLabel.Text = 'Start A';
			app.StartAEditField = uieditfield(app.GridLayout2, 'numeric');
			app.StartAEditField.Value = -10;
			app.StartBEditFieldLabel = uilabel(app.GridLayout2);
			app.StartBEditFieldLabel.Text = 'Start B';
			app.StartBEditField = uieditfield(app.GridLayout2, 'numeric');
			app.StartBEditField.Value = 10;
			app.ArduinoAEditFieldLabel = uilabel(app.GridLayout2);
			app.ArduinoAEditFieldLabel.Text = 'Arduino A';
			app.ArduinoAEditField = uieditfield(app.GridLayout2, 'text');
			app.ArduinoBEditFieldLabel = uilabel(app.GridLayout2);
			app.ArduinoBEditFieldLabel.Text = 'Arduino B';
			app.ArduinoBEditField = uieditfield(app.GridLayout2, 'text');
			app.StimulusDropDownLabel = uilabel(app.GridLayout2);
			app.StimulusDropDownLabel.Text = 'Stimulus';
			app.StimulusDropDown = uidropdown(app.GridLayout2);
			app.StimulusDropDown.Items = {'ball1.png', 'ball2.png', 'ball3.png', 'moon.png', 'star.png', 'heptagon.png'};
			app.StimulusDropDown.Value = 'ball1.png';
			app.WallColourEditFieldLabel = uilabel(app.GridLayout2);
			app.WallColourEditFieldLabel.Text = 'Wall Colour';
			app.WallColourEditField = uieditfield(app.GridLayout2, 'text');
			app.WallColourEditField.Value = '[ 0.3 0.3 0.5 1]';
			app.DividerWidthEditFieldLabel = uilabel(app.GridLayout2);
			app.DividerWidthEditFieldLabel.Text = 'Divider Width';
			app.DividerWidthEditField = uieditfield(app.GridLayout2, 'numeric');
			app.DividerWidthEditField.Value = 2;
			app.DistanceEditFieldLabel = uilabel(app.GridLayout2);
			app.DistanceEditFieldLabel.Text = 'Distance';
			app.DistanceEditField = uieditfield(app.GridLayout2, 'numeric');
			app.DistanceEditField.Value = 57.3;
			app.PxPerCmEditFieldLabel = uilabel(app.GridLayout2);
			app.PxPerCmEditFieldLabel.Text = 'PxPerCm';
			app.PxPerCmEditField = uieditfield(app.GridLayout2, 'numeric');
			app.PxPerCmEditField.Value = 36;
			app.VerboseCheckBox = uicheckbox(app.GridLayout2);
			app.VerboseCheckBox.Text = 'Verbose';
			app.SideDropDownLabel = uilabel(app.GridLayout2);
			app.SideDropDownLabel.Text = 'Side';
			app.SideDropDown = uidropdown(app.GridLayout2);
			app.SideDropDown.Items = {'Front', 'Back', 'Both'};
			app.SideDropDown.Value = 'Front';
			app.NumberofTrialsEditFieldLabel = uilabel(app.GridLayout2);
			app.NumberofTrialsEditFieldLabel.Text = 'Number of Trials';
			app.NumberofTrialsEditField = uieditfield(app.GridLayout2, 'numeric');
			app.NumberofTrialsEditField.Value = 100;
			app.TrialTimeEditFieldLabel = uilabel(app.GridLayout2);
			app.TrialTimeEditFieldLabel.Text = 'Trial Time';
			app.TrialTimeEditField = uieditfield(app.GridLayout2, 'numeric');
			app.TrialTimeEditField.Value = 8;
			app.CoopTimerEditFieldLabel = uilabel(app.GridLayout2);
			app.CoopTimerEditFieldLabel.Text = 'Co-op Timer';
			app.CoopTimerEditField = uieditfield(app.GridLayout2, 'numeric');
			app.CoopTimerEditField.Value = 0.1;
			app.TogglePedestalCheckBox = uicheckbox(app.GridLayout2);
			app.TogglePedestalCheckBox.Text = 'Toggle Pedestal';
			app.DebugCheckBox = uicheckbox(app.GridLayout2);
			app.DebugCheckBox.Text = 'Debug';
			app.nirSmartIPEditFieldLabel = uilabel(app.GridLayout2);
			app.nirSmartIPEditFieldLabel.Text = 'nirSmart IP';
			app.nirSmartIPEditField = uieditfield(app.GridLayout2, 'text');
			app.nirSmartIPEditField.Value = '127.0.0.1:5000';
			app.PedestalHeightEditFieldLabel = uilabel(app.GridLayout2);
			app.PedestalHeightEditFieldLabel.Text = 'Pedestal Height';
			app.PedestalHeightEditField = uieditfield(app.GridLayout2, 'numeric');
			app.PedestalHeightEditField.Value = 2;
			app.PedestalWidthEditFieldLabel = uilabel(app.GridLayout2);
			app.PedestalWidthEditFieldLabel.Text = 'Pedestal Width';
			app.PedestalWidthEditField = uieditfield(app.GridLayout2, 'numeric');
			app.PedestalWidthEditField.Value = 4;
			app.RewardTypeADropDownLabel = uilabel(app.GridLayout2);
			app.RewardTypeADropDownLabel.Text = 'Reward Type A';
			app.RewardTypeADropDown = uidropdown(app.GridLayout2);
			app.RewardTypeADropDown.Items = {'TTL', 'food'};
			app.RewardTypeADropDown.Value = 'TTL';
			app.RewardTypeBDropDownLabel = uilabel(app.GridLayout2);
			app.RewardTypeBDropDownLabel.Text = 'Reward Type B';
			app.RewardTypeBDropDown = uidropdown(app.GridLayout2);
			app.RewardTypeBDropDown.Items = {'TTL', 'food'};
			app.RewardTypeBDropDown.Value = 'TTL';
			app.ITICorrectEditFieldLabel = uilabel(app.GridLayout2);
			app.ITICorrectEditFieldLabel.Text = 'ITI Correct';
			app.ITICorrectEditField = uieditfield(app.GridLayout2, 'numeric');
			app.ITICorrectEditField.Value = 1;
			app.ITIIncorrectEditFieldLabel = uilabel(app.GridLayout2);
			app.ITIIncorrectEditFieldLabel.Text = 'ITI Incorrect';
			app.ITIIncorrectEditField = uieditfield(app.GridLayout2, 'numeric');
			app.ITIIncorrectEditField.Value = 3.9;
			app.FrontDeviceDropDownLabel = uilabel(app.GridLayout2);
			app.FrontDeviceDropDownLabel.Text = 'Front Device';
			app.FrontDeviceDropDown = uidropdown(app.GridLayout2);
			app.FrontDeviceDropDown.Items = {'1', '2'};
			app.FrontDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @FrontDeviceDropDownValueChanged, true);
			app.FrontDeviceDropDown.Value = '1';
			app.BackDeviceDropDownLabel = uilabel(app.GridLayout2);
			app.BackDeviceDropDownLabel.Text = 'Back Device';
			app.BackDeviceDropDown = uidropdown(app.GridLayout2);
			app.BackDeviceDropDown.Items = {'1', '2'};
			app.BackDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @BackDeviceDropDownValueChanged, true);
			app.BackDeviceDropDown.Value = '2';
			app.EnvyModeCheckBox = uicheckbox(app.GridLayout2);
			app.EnvyModeCheckBox.Text = 'Envy Mode';
			app.RightPanel = uipanel(app.GridLayout);
			app.GridLayout3 = uigridlayout(app.RightPanel);
			app.UIAxis2 = uiaxes(app.GridLayout3);
			title(app.UIAxis2, 'Performance')
			xlabel(app.UIAxis2, 'Trial')
			ylabel(app.UIAxis2, 'Correct')
			zlabel(app.UIAxis2, 'Z')
			app.UIAxis1 = uiaxes(app.GridLayout3);
			title(app.UIAxis1, {''; 'Ball Position'})
			xlabel(app.UIAxis1, 'X')
			ylabel(app.UIAxis1, 'Y')
			zlabel(app.UIAxis1, 'Z')
			% Show the figure after all components are created
			app.PingPongTaskUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = PingPong

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.PingPongTaskUIFigure)
			else

				% Focus the running singleton app
				figure(runningApp.PingPongTaskUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.PingPongTaskUIFigure)
		end
	end
end